classdef SOLINDAR_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SOLINDARPanel             matlab.ui.container.Panel
        DistancecmEditFieldLabel  matlab.ui.control.Label
        DistancecmEditField       matlab.ui.control.NumericEditField
        AngleLabel                matlab.ui.control.Label
        AngleEditField            matlab.ui.control.NumericEditField
        LOGSwitchLabel            matlab.ui.control.Label
        LOGSwitch                 matlab.ui.control.ToggleSwitch
        Gauge_2                   matlab.ui.control.Gauge
        SONARButton               matlab.ui.control.StateButton
        LIDARButton               matlab.ui.control.StateButton
        FUSIONButton              matlab.ui.control.StateButton
    end

    properties (Access = private)              
        r1,r2,th,deg;            % Variables de prueba
        fileID;                  % Txt de log
        Ej1,Ej2;                 % Plots de Polar Axes    
    end                 

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Variables aleatorias
            app.th = -3*pi/4:3*pi/(2*72):3*pi/4;
            app.r1 = randi(200,73,1);
            app.r2 = randi(200,73,1);
            app.deg = app.th*180/pi;
            
            % Creando polar axes
            pax = polaraxes(app.SOLINDARPanel);
            pax.NextPlot = 'add';
            pax.ThetaZeroLocation = 'top';            
            pax.ThetaDir = 'clockwise';
            pax.ThetaTickLabel = {'0','30','60','90','120','150','180','-150','-120','-90','-60','-30'};
            pax.RLim = [0 200];
            pax.RTick = 0:50:200;
            pax.ThetaMinorGrid = 'on';
            pax.Position = [-0.03 0.1100 0.7750 0.7750];
            
            % Graficando
            app.Ej1 = polarscatter(pax,app.th,app.r1,'filled');
            app.Ej2 = polarscatter(pax,app.th,app.r2,'filled');            
        end

        % Value changed function: SONARButton
        function SONARButtonValueChanged(app, event)
            value = app.SONARButton.Value;
            app.Ej1.Visible = value;
        end

        % Value changed function: LIDARButton
        function LIDARButtonValueChanged(app, event)
            value = app.LIDARButton.Value;
            app.Ej2.Visible = value;
        end

        % Value changed function: LOGSwitch
        function LOGSwitchValueChanged(app, event)
            value = app.LOGSwitch.Value;
            if strcmp(value,'On')
                % Abrir archivo txt dependiendo del switch
                if isfile('Demo.txt')                          
                    app.fileID = fopen('Demo.txt','w');
                else 
                    app.fileID = fopen('Demo.txt','wt+');
                end
                fseek(app.fileID,0,'bof');
                fprintf(app.fileID,'DateTime               Angle  Lidar   Sonar\r\n');                
                for i=1:73
                    if (app.th(i) < 0) 
                      fprintf(app.fileID,'%s %.2f  %.2f  %.2f\r\n',(datestr(datetime('now')))',round(app.deg(i),3)',round(app.r2(i),2)',round(app.r1(i),2)');
                    else
                      fprintf(app.fileID,'%s  %.2f  %.2f  %.2f\r\n',(datestr(datetime('now')))',round(app.deg(i),3)',round(app.r2(i),2)',round(app.r1(i),2)');
                    end
                end                
            else
                fclose(app.fileID);                
            end    
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'EC-3883: PROYECTOS III SOLINDAR_DEMO';
            app.SOLINDARPanel = uipanel(app.UIFigure);
            app.SOLINDARPanel.Title = 'SOLINDAR_2D_VISUALIZATION_PANEL';
            app.DistancecmEditFieldLabel = uilabel(app.SOLINDARPanel);
            app.DistancecmEditFieldLabel.Text = 'Distance (cm)';
            app.DistancecmEditField = uieditfield(app.SOLINDARPanel, 'numeric');
            app.AngleLabel = uilabel(app.SOLINDARPanel);
            app.AngleLabel.Text = 'Angle (⁰)';
            app.AngleEditField = uieditfield(app.SOLINDARPanel, 'numeric');
            app.LOGSwitchLabel = uilabel(app.SOLINDARPanel);
            app.LOGSwitchLabel.Text = 'LOG';
            app.LOGSwitch = uiswitch(app.SOLINDARPanel, 'toggle');
            app.LOGSwitch.ValueChangedFcn = createCallbackFcn(app, @LOGSwitchValueChanged, true);
            app.Gauge_2 = uigauge(app.SOLINDARPanel, 'circular');
            app.Gauge_2.Limits = [-150 150];
            app.Gauge_2.MajorTicks = [-120 -80 -45 0 45 80 120];
            app.SONARButton = uibutton(app.SOLINDARPanel, 'state');
            app.SONARButton.ValueChangedFcn = createCallbackFcn(app, @SONARButtonValueChanged, true);
            app.SONARButton.Text = 'SONAR';
            app.LIDARButton = uibutton(app.SOLINDARPanel, 'state');
            app.LIDARButton.ValueChangedFcn = createCallbackFcn(app, @LIDARButtonValueChanged, true);
            app.LIDARButton.Text = 'LIDAR';
            app.FUSIONButton = uibutton(app.SOLINDARPanel, 'state');
            app.FUSIONButton.Text = 'FUSION';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SOLINDAR_demo

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end