classdef Torres_SOLINDAR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SOLINDARPanel          matlab.ui.container.Panel
        UserEntryMsgLabel      matlab.ui.control.Label
        UserEntryMsgEditField  matlab.ui.control.EditField
        MsgtoSendLabel         matlab.ui.control.Label
        MsgtoSendEditField     matlab.ui.control.EditField
        MsgReceivedLabel       matlab.ui.control.Label
        MsgReceivedEditField   matlab.ui.control.EditField
        SENDButton             matlab.ui.control.Button
        TW1DropDownLabel       matlab.ui.control.Label
        TW1DropDown            matlab.ui.control.DropDown
        TW2DropDownLabel       matlab.ui.control.Label
        TW2DropDown            matlab.ui.control.DropDown
        TW3DropDownLabel       matlab.ui.control.Label
        TW3DropDown            matlab.ui.control.DropDown
        TW4DropDownLabel       matlab.ui.control.Label
        TW4DropDown            matlab.ui.control.DropDown
        DATATYPEDropDownLabel  matlab.ui.control.Label
        DATATYPEDropDown       matlab.ui.control.DropDown
        OKTOSENDLampLabel      matlab.ui.control.Label
        OKTOSENDLamp           matlab.ui.control.Lamp
        DialogLabel            matlab.ui.control.Label
        DialogEditField        matlab.ui.control.EditField
    end

    properties (Access = private)              
        s;                        % Puerto serial COM_7     
        x,bytes;                  % Tamaño de buffer (bytes disponibles)
        DataMsg,Z1,Z2,Z3,Z4;      % Data del mensaje y zonas de torres
        Entry;                    % Entrada del usuario       
        MsgRec,MsgtoSend;         % Mensaje que se recibe y que se envía
    end                        

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Inicializando puerto serial
            app.x = 16;
            delete(instrfind(('port'),('COM7')));
            app.s = serial('COM7','BaudRate', 115200); 
            set(app.s,'InputBufferSize',app.x);        
            set(app.s,'Terminator','V');
            set(app.s,'BytesAvailableFcnMode','byte');                                                
            % Mensaje para usuario
            app.DialogEditField.Value = 'Introduzca mensaje arriba';            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Cerrar puerto serial cuando se cierre la app
            global On
            if strcmp(app.s.Status, 'open')
                fclose(app.s);
            end
            On = 0;
            instrreset
            delete(app);
        end

        % Value changed function: TW1DropDown
        function TW1DropDownValueChanged(app, event)
            value = app.TW1DropDown.Value;
            app.Z1 = str2num(value);
        end

        % Value changed function: TW2DropDown
        function TW2DropDownValueChanged(app, event)
            value = app.TW2DropDown.Value;
            app.Z2 = str2num(value);
        end

        % Value changed function: TW3DropDown
        function TW3DropDownValueChanged(app, event)
            value = app.TW3DropDown.Value;
            app.Z3 = str2num(value);
        end

        % Value changed function: TW4DropDown
        function TW4DropDownValueChanged(app, event)
            value = app.TW4DropDown.Value;
            app.Z4 = str2num(value);
        end

        % Button pushed function: SENDButton
        function SENDButtonPushed(app, event)
            fopen(app.s)                                  
            app.MsgtoSend = Send_Message(app.DataMsg,app.Z1,app.Z2,app.Z3,app.Z4);
            flushinput(app.s)
            fwrite(app.s,app.MsgtoSend) 
            global On            
            On = 1;
            while On                
                app.bytes = app.s.BytesAvailable;
                if app.bytes > 3
                % Read serial del puerto
                app.DataMsg = fread(app.s,4,'uint8');                 
                % Decodificacion
                [app.MsgRec] = Receive_Message(app.DataMsg);
                % Mostrar mensaje recibido en el campo
                app.MsgReceivedEditField.Value = num2str(app.MsgRec);                                
                end                
                drawnow 
            end
        end

        % Value changed function: UserEntryMsgEditField
        function UserEntryMsgEditFieldValueChanged(app, event)
            app.Entry = app.UserEntryMsgEditField.Value; 
        end

        % Value changed function: DATATYPEDropDown
        function DATATYPEDropDownValueChanged(app, event)
            switch app.DATATYPEDropDown.Value
                case 'DEFAULT'
                    app.UserEntryMsgEditField.Value = '';                    
                    app.DialogEditField.Value = 'Introduzca mensaje arriba';
                    app.MsgtoSendEditField.Value = '';
                    app.MsgReceivedEditField.Value = '';
                    app.OKTOSENDLamp.Color = [1 1 0];
                    flushinput(app.s)
                    fclose(app.s)
                case 'ASCII' 
                    app.Entry = app.Entry(1); % Asignación de valor Ascii correspondiente
                    if (app.Entry == 'a')
                        app.DataMsg = 97;                   
                    elseif (app.Entry == 'b')
                        app.DataMsg = 98;
                    elseif (app.Entry == 'c')
                        app.DataMsg = 99;                    
                    elseif (app.Entry == 'd')
                        app.DataMsg = 100;                    
                    elseif (app.Entry == 'e')
                        app.DataMsg = 101;                    
                    elseif (app.Entry == 'f')
                        app.DataMsg = 102;                   
                    elseif (app.Entry == 'g')
                        app.DataMsg = 103;                    
                    elseif (app.Entry == 'h')
                        app.DataMsg = 104;                    
                    elseif (app.Entry == 'i')
                        app.DataMsg = 105;                    
                    elseif (app.Entry == 'j')
                        app.DataMsg = 106;                    
                    elseif (app.Entry == 'k')
                        app.DataMsg = 107;                    
                    elseif (app.Entry == 'l')
                        app.DataMsg = 108;                    
                    elseif (app.Entry == 'm')
                        app.DataMsg = 109;                                      
                    elseif (app.Entry == 'n')
                        app.DataMsg = 110;                                            
                    elseif (app.Entry == 'o')
                        app.DataMsg = 111;                                            
                    elseif (app.Entry == 'p')
                        app.DataMsg = 112;                                            
                    elseif (app.Entry == 'q')
                        app.DataMsg = 113;                                            
                    elseif (app.Entry == 'r')
                        app.DataMsg = 114;                                            
                    elseif (app.Entry == 's')
                        app.DataMsg = 115;                                            
                    elseif (app.Entry == 't')
                        app.DataMsg = 116;                                            
                    elseif (app.Entry == 'u')
                        app.DataMsg = 117;                                            
                    elseif (app.Entry == 'v')
                        app.DataMsg = 118;                                            
                    elseif (app.Entry == 'w')
                        app.DataMsg = 119;                                            
                    elseif (app.Entry == 'x')
                        app.DataMsg = 120;                                           
                    elseif (app.Entry == 'y')
                        app.DataMsg = 121;                                            
                    elseif (app.Entry == 'z')
                        app.DataMsg = 122;                                            
                    elseif (app.Entry == '0')
                        app.DataMsg = 48;                                            
                    elseif (app.Entry == '1')
                        app.DataMsg = 49;                                            
                    elseif (app.Entry == '2')
                        app.DataMsg = 50;                                            
                    elseif (app.Entry == '3')
                        app.DataMsg = 51;                                            
                    elseif (app.Entry == '4')
                        app.DataMsg = 52;                                            
                    elseif (app.Entry == '5')
                        app.DataMsg = 53;                                            
                    elseif (app.Entry == '6')
                        app.DataMsg = 54;                                           
                    elseif (app.Entry == '7')
                        app.DataMsg = 55;                                            
                    elseif (app.Entry == '8')
                        app.DataMsg = 56;                                            
                    elseif (app.Entry == '9')
                        app.DataMsg = 57; 
                    elseif (app.Entry == '{')
                        app.DataMsg = 123;
                    elseif (app.Entry == '}')
                        app.DataMsg = 125;     
                    elseif (app.Entry == '|')
                        app.DataMsg = 124; 
                    elseif (app.Entry == '[')
                        app.DataMsg = 91;
                    elseif (app.Entry == ']')
                        app.DataMsg = 93;
                    elseif (app.Entry == '\')
                        app.DataMsg = 92;
                    elseif (app.Entry == '^')
                        app.DataMsg = 94;
                    elseif (app.Entry == '_')
                        app.DataMsg = 95; 
                    elseif (app.Entry == '`')
                        app.DataMsg = 96;     
                    elseif (app.Entry == '!')
                        app.DataMsg = 33; 
                    elseif (app.Entry == '"')
                        app.DataMsg = 34;    
                    elseif (app.Entry == '#')
                        app.DataMsg = 35;
                    elseif (app.Entry == '$')
                        app.DataMsg = 36;
                    elseif (app.Entry == '%')
                        app.DataMsg = 37;
                    elseif (app.Entry == '&')
                        app.DataMsg = 38; 
                    elseif (app.Entry == '/')
                        app.DataMsg = 47;
                    elseif (app.Entry == '(')
                        app.DataMsg = 40;
                    elseif (app.Entry == ')')
                        app.DataMsg = 41;
                    elseif (app.Entry == '=')
                        app.DataMsg = 61;
                    elseif (app.Entry == '?')
                        app.DataMsg = 63;
                    elseif (app.Entry == ':')
                        app.DataMsg = 58;
                    elseif (app.Entry == ';')
                        app.DataMsg = 59;
                    elseif (app.Entry == '<')
                        app.DataMsg = 60;
                    elseif (app.Entry == '>')
                        app.DataMsg = 62;    
                    elseif (app.Entry == '+')
                        app.DataMsg = 43;
                    elseif (app.Entry == '*')
                        app.DataMsg = 42;
                    elseif (app.Entry == '-')
                        app.DataMsg = 45;
                    else                                               
                        app.DialogEditField.Value = 'Caracter no válido';
                        app.MsgtoSendEditField.Value = '';
                        app.OKTOSENDLamp.Color = [1 0 0];
                    end
                    app.OKTOSENDLamp.Color = [0 1 0];
                    app.MsgtoSendEditField.Value = app.Entry(1);
                    app.DialogEditField.Value ='Solo 1 caracter posible';                   
                    app.MsgReceivedEditField.Value = char(app.MsgRec);                
                case 'DECIMAL'                    
                    if isstrprop(app.Entry,'digit')
                        aux = str2num(app.Entry);
                        % Debe ser un decimal entre 0 y 127
                        if (aux <= 127) && (aux > -1)            
                            app.MsgtoSendEditField.Value = num2str(app.Entry);
                            app.OKTOSENDLamp.Color = [0 1 0];
                            app.DialogEditField.Value = '';
                            app.DataMsg = str2num(app.Entry);
                        else
                            % Si el número es mayor a la máxima capacidad del byte:
                            app.MsgtoSendEditField.Value = '127';
                            app.OKTOSENDLamp.Color = [0 1 0];
                            app.DialogEditField.Value = 'Se enviará 127 (máximo decimal)';
                            app.DataMsg = 127;
                        end
                    else
                        app.DialogEditField.Value = 'Error: mensaje no decimal';
                        app.MsgtoSendEditField.Value = '';
                        app.OKTOSENDLamp.Color = [1 0 0];
                    end                       
                case 'BIN'                     
                    % Flag de error
                    e = 0;                                       
                    % Se verifica que todos los dígitos sean 0 o 1
                    for c=1:length(app.Entry) 
                       aux = app.Entry(c);
                       if(aux ~= '0') && (aux ~= '1')
                           e = 1;
                       end
                    end                    
                    if e == 1
                        app.DialogEditField.Value ='Error: mensaje no binario';
                        app.MsgtoSendEditField.Value = '';
                        app.OKTOSENDLamp.Color = [1 0 0];
                    else
                        % Caso longitud de mensaje más de 8 bits: se toman los 8 primeros
                        if length(app.Entry) > 8                           
                           app.MsgtoSendEditField.Value = app.Entry(1:8);
                           app.DialogEditField.Value ='Solo 8 bits de capacidad';
                           app.OKTOSENDLamp.Color = [0 1 0];
                           app.DataMsg = app.Entry(1:8);
                           app.DataMsg = bin2dec(app.DataMsg);
                        else
                           app.MsgtoSendEditField.Value = app.Entry;
                           app.OKTOSENDLamp.Color = [0 1 0];
                           app.DialogEditField.Value ='';
                           app.DataMsg = app.Entry;
                           app.DataMsg = bin2dec(app.DataMsg);
                        end
                    end                    
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'EC-3883: PROYECTOS III TORRES SOLINDAR';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SOLINDARPanel = uipanel(app.UIFigure);
            app.SOLINDARPanel.Title = 'TORRES_SOLINDAR';
            app.UserEntryMsgLabel = uilabel(app.SOLINDARPanel);
            app.UserEntryMsgLabel.Text = 'User Entry Msg.';
            app.UserEntryMsgEditField = uieditfield(app.SOLINDARPanel, 'text');
            app.UserEntryMsgEditField.ValueChangedFcn = createCallbackFcn(app, @UserEntryMsgEditFieldValueChanged, true);
            app.MsgtoSendLabel = uilabel(app.SOLINDARPanel);
            app.MsgtoSendLabel.Text = 'Msg. to Send';
            app.MsgtoSendEditField = uieditfield(app.SOLINDARPanel, 'text');
            app.MsgtoSendEditField.Editable = 'off';
            app.MsgReceivedLabel = uilabel(app.SOLINDARPanel);
            app.MsgReceivedLabel.Text = 'Msg. Received';
            app.MsgReceivedEditField = uieditfield(app.SOLINDARPanel, 'text');
            app.MsgReceivedEditField.Editable = 'off';
            app.SENDButton = uibutton(app.SOLINDARPanel, 'push');
            app.SENDButton.ButtonPushedFcn = createCallbackFcn(app, @SENDButtonPushed, true);
            app.SENDButton.Text = 'SEND';
            app.TW1DropDownLabel = uilabel(app.SOLINDARPanel);
            app.TW1DropDownLabel.Text = 'TW1';
            app.TW1DropDown = uidropdown(app.SOLINDARPanel);
            app.TW1DropDown.Items = {'---------', 'Zona 1', 'Zona 2', 'Zona 3', 'Zona 4', 'Zona 5', 'Zona 6'};
            app.TW1DropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6'};
            app.TW1DropDown.ValueChangedFcn = createCallbackFcn(app, @TW1DropDownValueChanged, true);
            app.TW1DropDown.Value = '1';
            app.TW2DropDownLabel = uilabel(app.SOLINDARPanel);
            app.TW2DropDownLabel.Text = 'TW2';
            app.TW2DropDown = uidropdown(app.SOLINDARPanel);
            app.TW2DropDown.Items = {'---------', 'Zona 1', 'Zona 2', 'Zona 3', 'Zona 4', 'Zona 5', 'Zona 6'};
            app.TW2DropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6'};
            app.TW2DropDown.ValueChangedFcn = createCallbackFcn(app, @TW2DropDownValueChanged, true);
            app.TW2DropDown.Value = '1';
            app.TW3DropDownLabel = uilabel(app.SOLINDARPanel);
            app.TW3DropDownLabel.Text = 'TW3';
            app.TW3DropDown = uidropdown(app.SOLINDARPanel);
            app.TW3DropDown.Items = {'---------', 'Zona 1', 'Zona 2', 'Zona 3', 'Zona 4', 'Zona 5', 'Zona 6'};
            app.TW3DropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6'};
            app.TW3DropDown.ValueChangedFcn = createCallbackFcn(app, @TW3DropDownValueChanged, true);
            app.TW3DropDown.Value = '1';
            app.TW4DropDownLabel = uilabel(app.SOLINDARPanel);
            app.TW4DropDownLabel.Text = 'TW4';
            app.TW4DropDown = uidropdown(app.SOLINDARPanel);
            app.TW4DropDown.Items = {'---------', 'Zona 1', 'Zona 2', 'Zona 3', 'Zona 4', 'Zona 5', 'Zona 6'};
            app.TW4DropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6'};
            app.TW4DropDown.ValueChangedFcn = createCallbackFcn(app, @TW4DropDownValueChanged, true);
            app.TW4DropDown.Value = '1';
            app.DATATYPEDropDownLabel = uilabel(app.SOLINDARPanel);
            app.DATATYPEDropDownLabel.Text = 'DATATYPE';
            app.DATATYPEDropDown = uidropdown(app.SOLINDARPanel);
            app.DATATYPEDropDown.Items = {'DEFAULT', 'BIN', 'ASCII', 'DECIMAL', ''};
            app.DATATYPEDropDown.ValueChangedFcn = createCallbackFcn(app, @DATATYPEDropDownValueChanged, true);
            app.DATATYPEDropDown.Value = 'DEFAULT';
            app.OKTOSENDLampLabel = uilabel(app.SOLINDARPanel);
            app.OKTOSENDLampLabel.Text = 'OK TO SEND';
            app.OKTOSENDLamp = uilamp(app.SOLINDARPanel);
            app.DialogLabel = uilabel(app.SOLINDARPanel);
            app.DialogLabel.Text = 'Dialog';
            app.DialogEditField = uieditfield(app.SOLINDARPanel, 'text');
            app.DialogEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Torres_SOLINDAR

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end